classdef fnirs_MontageEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        MaxDistancemmEditField         matlab.ui.control.NumericEditField
        MaxDistancemmEditField_2Label  matlab.ui.control.Label
        ViewChannelsCheckBox           matlab.ui.control.CheckBox
        DuplicatesListBox              matlab.ui.control.ListBox
        DuplicatesListBoxLabel         matlab.ui.control.Label
        NChannelsEditField             matlab.ui.control.NumericEditField
        NChannelsEditFieldLabel        matlab.ui.control.Label
        NDetectorsEditField            matlab.ui.control.NumericEditField
        NDetectorsEditFieldLabel       matlab.ui.control.Label
        NSourcesEditField              matlab.ui.control.NumericEditField
        NSourcesEditFieldLabel         matlab.ui.control.Label
        SaveConfigurationFilesButton   matlab.ui.control.Button
        MinDistancemmEditField         matlab.ui.control.NumericEditField
        MinDistancemmEditFieldLabel    matlab.ui.control.Label
        LoadCoordinateFileButton       matlab.ui.control.Button
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        ChannelsLabel                  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        RemoveDetectors                matlab.ui.control.Button
        AddDetectors                   matlab.ui.control.Button
        RemoveSources                  matlab.ui.control.Button
        AddSources                     matlab.ui.control.Button
        LocationListBox                matlab.ui.control.ListBox
        LocationListBoxLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        labels % labels
        coords % 3d positions
        braincolor
        scalpcolor
        surfaces
        listindices % indices of selected
        sources
        detectors
        az % view azimuth
        el % view elevation
        SDpairs % valid SD pairs by distance
    end
    
    methods (Access = private)
        
        % set up surface plotting
        function setupPlot(app)
            app.scalpcolor = [.8 .8 .8];
            app.braincolor = [200 120 105]./255;
            [pth,~,~]=fileparts(which('nirx_defaults'));
            try
                s = load(fullfile(pth,'templates','mni_surfaces.mat'));
                app.surfaces = s;
                app.az = 0;
                app.el = 90;
                setView(app);
            catch
                uialert(fig,"Surface file not found.","Invalid File");
            end
        end
        
        % plot optodes on surfaces
        function plot3D(app)
            cla(app.UIAxes,'reset');
            if isprop(app,"surfaces")
                s = patch(app.UIAxes,'vertices',app.surfaces.scalp.vertices,'faces',app.surfaces.scalp.faces,'edgecolor','none',...
                    'facecolor',app.scalpcolor);
                S = s.Vertices;
                alpha(s,.3);
                axis(app.UIAxes,'image');
                axis(app.UIAxes,'off');
                hold(app.UIAxes,'on');
                c = patch(app.UIAxes,'vertices',app.surfaces.brain.vertices,'faces',app.surfaces.brain.faces,'edgecolor','none',...
                        'facecolor',app.braincolor);
                % insert position plotting here
                if ~isempty(app.sources)
                    src3d = app.coords(app.sources,:);
                    srclabels = app.labels(app.sources);
                    [h(1),src_offsets] = nirx_plot_optode3d(src3d,S,app.surfaces.normals,'offset',10,'edgecolor',[1 0 0],...
                        'facealpha',.4,'facecolor',[1 0 0],'labels',srclabels,'axis',app.UIAxes);
                end
                if ~isempty(app.detectors)
                    det3d = app.coords(app.detectors,:);
                    detlabels = app.labels(app.detectors);
                    [h(2),det_offsets] = nirx_plot_optode3d(det3d,S,app.surfaces.normals,'offset',10,'edgecolor',[0 0 1],...
                       'facealpha',.4,'facecolor',[0 0 1],'labels',detlabels,'axis',app.UIAxes);
                end
                % plot 3d lines for channels
                if app.ViewChannelsCheckBox.Value
                    if ~isempty(app.SDpairs)
                        for chn = 1:app.NChannelsEditField.Value
                            src = app.SDpairs(chn,1);
                            det = app.SDpairs(chn,2);
                            line(app.UIAxes,[src_offsets(src,1) det_offsets(det,1)],[src_offsets(src,2) det_offsets(det,2)],...
                                [src_offsets(src,3) det_offsets(det,3)], 'LineWidth',2);
                        end
                    end
                end
                % set view
                camlight(app.UIAxes,'left');
                camlight(app.UIAxes,'right');
                lighting(app.UIAxes,'gouraud');
                setView(app);
                rotate3d(app.UIAxes,'on');
                hold(app.UIAxes,'off');
            end
        end
        
        % basic checks on input
        function checkSD(app)
            % if no indices, no sources or detectors have been selected
            if ~isempty(app.listindices)
                % check #s
                nopt = length(app.sources)+length(app.detectors);
                if nopt ~= length(app.listindices)
                    app.listindices = unique([app.sources app.detectors]);
                end
                % check sources not also detectors             
                membership = ismember(app.sources,app.detectors);
                if any(membership)
                    dupind = find(membership);
                    listind = app.sources(dupind);
                    uialert(app.UIFigure,"Duplicate sensors in sources and detectors.\nPlease remove one or more.","Duplicates!");
                    for opt = 1:length(dupind)
                        app.DuplicatesListBox.Items = [string(app.DuplicatesListBox.Items) ...
                            string(app.LocationListBox.Items(listind(opt)))];
                    end
                else
                    app.DuplicatesListBox.Items = {};
                end
            else
                app.sources = [];
                app.detectors = [];
            end
            app.NDetectorsEditField.Value = length(app.detectors);
            app.NSourcesEditField.Value = length(app.sources);
        end

        % get the view
        function getView(app)
            [app.az, app.el] = view(app.UIAxes);
        end
        
        % set the view
        function setView(app)
            view(app.UIAxes,app.az,app.el);
        end
        
        % make channels from SD pairs, given distance constraints
        function createChannels(app)
            range = [app.MinDistancemmEditField.Value app.MaxDistancemmEditField.Value];
            if range(2) > range(1)
                Spos = app.coords(app.sources,:);
                Dpos = app.coords(app.detectors,:);
                mask = ones(length(app.sources),length(app.detectors));
                optind = find(mask);
                [Sfull,Dfull] = ind2sub(size(mask),optind);
                %Sfull = Sfull';
                %Dfull = Dfull';
                for ii = 1:length(Sfull)
                    Sloc = Spos(Sfull(ii),:);
                    Dloc = Dpos(Dfull(ii),:);      
                    chdist(ii,:) = sqrt(sum((Sloc - Dloc).^2, 2));
                end
                app.SDpairs = [Sfull Dfull];
                ch_ind = find(chdist <= range(2) & chdist >= range(1));
                app.SDpairs = app.SDpairs(ch_ind,:);
                app.NChannelsEditField.Value = length(ch_ind);
                app.UITable.Data = [];
                for chn = 1:app.NChannelsEditField.Value
                    app.UITable.Data = [app.UITable.Data;
                        [string(chn) ...
                        app.labels(app.sources(app.SDpairs(chn,1))) ...
                        app.labels(app.detectors(app.SDpairs(chn,2)))]];
                end
            end
        end
        
        % update the 3d view
        function updateView(app)
            createChannels(app);
            getView(app);
            checkSD(app);
            plot3D(app);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCoordinateFileButton
        function LoadCoordinateFileButtonPushed(app, event)
            [posfile, pth, ~] = uigetfile('*.mat','Select file with sensor names and positions...');
            p = load(fullfile(pth,posfile));
            app.coords = p.coords;
            app.labels = p.labels;
            app.LocationListBox.Items = app.labels;
            app.LocationListBox.ItemsData = 1:length(app.labels);
            app.AddDetectors.Enable = "on";
            app.AddSources.Enable = "on";
            app.RemoveSources.Enable = "on";
            app.RemoveDetectors.Enable = "on";
            app.LocationListBox.Enable = "on";
            setupPlot(app);
            plot3D(app);
        end

        % Button pushed function: AddSources
        function AddSourcesButtonPushed(app, event)
            app.listindices = unique([app.listindices app.LocationListBox.Value]);
            app.sources = unique([app.sources app.LocationListBox.Value]);
            updateView(app);
        end

        % Button pushed function: RemoveSources
        function RemoveSourcesButtonPushed(app, event)
            app.listindices = setdiff(app.listindices, app.LocationListBox.Value);
            app.sources = setdiff(app.sources,app.LocationListBox.Value);
            updateView(app);
        end

        % Button pushed function: AddDetectors
        function AddDetectorsButtonPushed(app, event)
            app.listindices = unique([app.listindices app.LocationListBox.Value]);
            app.detectors = unique([app.detectors app.LocationListBox.Value]);
            updateView(app);
        end

        % Button pushed function: RemoveDetectors
        function RemoveDetectorsButtonPushed(app, event)
            app.listindices = setdiff(app.listindices, app.LocationListBox.Value);
            app.detectors = setdiff(app.detectors,app.LocationListBox.Value);
            updateView(app);
        end

        % Value changed function: MinDistancemmEditField
        function MinDistancemmEditFieldValueChanged(app, event)
            updateView(app);
        end

        % Value changed function: MaxDistancemmEditField
        function MaxDistancemmEditFieldValueChanged(app, event)
            updateView(app);
        end

        % Value changed function: ViewChannelsCheckBox
        function ViewChannelsCheckBoxValueChanged(app, event)
            updateView(app);
        end

        % Button pushed function: SaveConfigurationFilesButton
        function SaveConfigurationFilesButtonPushed(app, event)
            fp = fopen("SD.csv",'w');
            fprintf(fp,'S,D\n');
            for chn = 1:size(app.SDpairs,1)
                fprintf(fp,'%d,%d\n',app.SDpairs(chn,1),app.SDpairs(chn,2));
            end
            fclose(fp);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x', 335};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadCoordinateFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadCoordinateFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoordinateFileButtonPushed, true);
            app.LoadCoordinateFileButton.Text = 'Load Coordinate File';
            app.MinDistancemmEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDistancemmEditFieldLabel.Text = 'Min Distance (mm)';
            app.MinDistancemmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinDistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistancemmEditFieldValueChanged, true);
            app.SaveConfigurationFilesButton = uibutton(app.LeftPanel, 'push');
            app.SaveConfigurationFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationFilesButtonPushed, true);
            app.SaveConfigurationFilesButton.Text = 'Save Configuration Files';
            app.NSourcesEditFieldLabel = uilabel(app.LeftPanel);
            app.NSourcesEditFieldLabel.Text = 'N Sources';
            app.NSourcesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NSourcesEditField.Editable = 'off';
            app.NDetectorsEditFieldLabel = uilabel(app.LeftPanel);
            app.NDetectorsEditFieldLabel.Text = 'N Detectors';
            app.NDetectorsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NDetectorsEditField.Editable = 'off';
            app.NChannelsEditFieldLabel = uilabel(app.LeftPanel);
            app.NChannelsEditFieldLabel.Text = 'N Channels';
            app.NChannelsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NChannelsEditField.Editable = 'off';
            app.DuplicatesListBoxLabel = uilabel(app.LeftPanel);
            app.DuplicatesListBoxLabel.Enable = 'off';
            app.DuplicatesListBoxLabel.Text = 'Duplicates';
            app.DuplicatesListBox = uilistbox(app.LeftPanel);
            app.DuplicatesListBox.Items = {};
            app.DuplicatesListBox.Enable = 'off';
            app.DuplicatesListBox.Value = {};
            app.ViewChannelsCheckBox = uicheckbox(app.LeftPanel);
            app.ViewChannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewChannelsCheckBoxValueChanged, true);
            app.ViewChannelsCheckBox.Text = 'View Channels';
            app.MaxDistancemmEditField_2Label = uilabel(app.LeftPanel);
            app.MaxDistancemmEditField_2Label.Text = 'Max Distance (mm)';
            app.MaxDistancemmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxDistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDistancemmEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Optodes and Channels Formed')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.LocationListBoxLabel = uilabel(app.RightPanel);
            app.LocationListBoxLabel.Enable = 'off';
            app.LocationListBoxLabel.Text = 'Location';
            app.LocationListBox = uilistbox(app.RightPanel);
            app.LocationListBox.Items = {'Nothing loaded'};
            app.LocationListBox.Multiselect = 'on';
            app.LocationListBox.Enable = 'off';
            app.LocationListBox.Value = {'Nothing loaded'};
            app.AddSources = uibutton(app.RightPanel, 'push');
            app.AddSources.ButtonPushedFcn = createCallbackFcn(app, @AddSourcesButtonPushed, true);
            app.AddSources.Enable = 'off';
            app.AddSources.Text = '+ Source(s)';
            app.RemoveSources = uibutton(app.RightPanel, 'push');
            app.RemoveSources.ButtonPushedFcn = createCallbackFcn(app, @RemoveSourcesButtonPushed, true);
            app.RemoveSources.Enable = 'off';
            app.RemoveSources.Text = '- Source(s)';
            app.AddDetectors = uibutton(app.RightPanel, 'push');
            app.AddDetectors.ButtonPushedFcn = createCallbackFcn(app, @AddDetectorsButtonPushed, true);
            app.AddDetectors.Enable = 'off';
            app.AddDetectors.Text = '+ Detectors(s)';
            app.RemoveDetectors = uibutton(app.RightPanel, 'push');
            app.RemoveDetectors.ButtonPushedFcn = createCallbackFcn(app, @RemoveDetectorsButtonPushed, true);
            app.RemoveDetectors.Enable = 'off';
            app.RemoveDetectors.Text = '- Detectors(s)';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Chn'; 'S'; 'D'};
            app.UITable.RowName = {};
            app.ChannelsLabel = uilabel(app.RightPanel);
            app.ChannelsLabel.Text = 'Channels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_MontageEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end