classdef fnirs_dataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fNIRSDataViewerUIFigure      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenFileMenu                 matlab.ui.container.Menu
        LoadOptodeCoordinatesMenu    matlab.ui.container.Menu
        SaveFileMenu                 matlab.ui.container.Menu
        QuitMenu                     matlab.ui.container.Menu
        PreprocessMenu               matlab.ui.container.Menu
        PlotOptodesChannelsMenu      matlab.ui.container.Menu
        TrimBadDataMenu              matlab.ui.container.Menu
        BadChannelsMenu              matlab.ui.container.Menu
        MBLLandMotionCorrectionMenu  matlab.ui.container.Menu
        FilterDataMenu               matlab.ui.container.Menu
        FirstLevelStatisticsMenu     matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ChannelsDropDown             matlab.ui.control.DropDown
        ChannelsDropDownLabel        matlab.ui.control.Label
        ScaleSpinner                 matlab.ui.control.Spinner
        ScaleSpinnerLabel            matlab.ui.control.Label
        OverlayEventsCheckBox        matlab.ui.control.CheckBox
        ChnMaxSpinner                matlab.ui.control.Spinner
        ChnMaxSpinnerLabel           matlab.ui.control.Label
        ChnMinSpinner                matlab.ui.control.Spinner
        ChnMinSpinnerLabel           matlab.ui.control.Label
        HbButtonGroup                matlab.ui.container.ButtonGroup
        HbOButton                    matlab.ui.control.RadioButton
        HbRButton                    matlab.ui.control.RadioButton
        HbTButton                    matlab.ui.control.RadioButton
        PlotTypeButtonGroup          matlab.ui.container.ButtonGroup
        StackedButton                matlab.ui.control.RadioButton
        ButterflyButton              matlab.ui.control.RadioButton
        RemoveBaselineCheckBox       matlab.ui.control.CheckBox
        WavelengthButtonGroup        matlab.ui.container.ButtonGroup
        nmButton_760                 matlab.ui.control.RadioButton
        nmButton_850                 matlab.ui.control.RadioButton
        DataTypeButtonGroup          matlab.ui.container.ButtonGroup
        HbButton                     matlab.ui.control.RadioButton
        WavelengthsButton            matlab.ui.control.RadioButton
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
        Raw_Data % Raw loaded data, all wl
        Working_Data % Whole dataset with all processes applied, both wl or hb
        Current_Data % Data showing in UIAxes - subset of working data, only 1 wl or hb type
        hb_Data % Data with Beer-Lambert applied
        hdr % header data
        time % time vector for plotting
        Ylim % Ymin and Ymax for raw/working data
        spacing % stacked plot spacing
        ch_ind % channel indices to show
        bad % bad channels, if any
        optpos % optode positions, if loaded
        optlabels % labels for optodes, if loaded
        chdist % channel distances, if computed
    end
    
    methods (Access = private)
        
        % waveform plotting function
        function  plotData(app,d)
            data = d.data;  
            if app.RemoveBaselineCheckBox.Value
                data = nirx_offset(data);
            end
            setChannelSpacing(app,data);
            selectedButton = app.PlotTypeButtonGroup.SelectedObject; % plot type
            scale = app.ScaleSpinner.Value;
            if strcmpi(selectedButton.Text,'Butterfly')
                plot(app.UIAxes, app.time, data * scale,'Color','k');
                app.UIAxes.YLabel.String = 'Amplitude';
                if ~isempty(d.badind)
                    hold(app.UIAxes,'on');
                    plot(app.UIAxes, app.time, data(:,d.badind) * scale,'Color','r');
                end
            else
                cla(app.UIAxes, 'reset');
                hold(app.UIAxes,"on");
                if ~isempty(d.badind)
                    isbad = ismember(1:d.nchan,d.badind)';
                else
                    isbad = zeros(d.nchan,1);
                end
                for ch=1:d.nchan
                    if isbad(ch)
                        plot(app.UIAxes, app.time, (data(:, ch) * scale)...
                            + (ch * app.spacing),'Color','r');
                    else
                        plot(app.UIAxes, app.time, (data(:, ch) * scale)...
                            + (ch * app.spacing),'Color','k');
                    end
                end
                hold(app.UIAxes,"off");
                app.UIAxes.YTickLabelMode = "manual";
                app.UIAxes.YTickMode = "manual";
                app.UIAxes.YTick = linspace(app.spacing,d.nchan*app.spacing,d.nchan);
                app.UIAxes.YTickLabel = string(d.chToPlot);
                app.UIAxes.YLabel.String = 'Channel #';
            end
      
            axis(app.UIAxes, "tight");
            app.UIAxes.XLabel.String = 'Time (s)';

            if app.OverlayEventsCheckBox.Value
                cmap = eventColorMap(app);
                hold(app.UIAxes,"on");
                y_min = app.UIAxes.YLim(1);
                y_max = app.UIAxes.YLim(2);
                % plot events over waveforms
                uvals = unique([app.hdr.events.code]);
                tvals = [app.hdr.events.time];
                for val = 1:length(uvals)
                    u_ons = find([app.hdr.events.code] == uvals(val));
                    for event=1:length(u_ons)
                        h(val) = line(app.UIAxes, [tvals(u_ons(event)) tvals(u_ons(event))], ...
                                    [y_min y_max],'color',cmap(val,:));
                    end
                end
                legend(h,string(unique([app.hdr.events.code])));
                hold(app.UIAxes,"off");
            end
        end

        % channel spacing
        function setChannelSpacing(app,data)
            Ymin = min(data,[],1);
            Ymax = max(data,[],1);
            app.spacing = 0.8 * max(Ymax - Ymin);
        end
        
        % color map for events
        function cmap = eventColorMap(app)
            cmap = [0 0 1;
                    1 0 0;
                    0 1 0;
                    1 1 0;
                    1 0 1;
                    0 1 1;
                    .5 1 .5;
                    .1 .4 .1];
        end
        
        function d = dataForPlot(app)
            minCh = app.ChnMinSpinner.Value;
            maxCh = app.ChnMaxSpinner.Value;
            if length(app.ch_ind) == app.hdr.nchan
                d.chToPlot = minCh:maxCh;
            else
                d.chToPlot = app.ch_ind;
            end
            d.data = app.Current_Data(:,d.chToPlot);
            if ~isempty(app.bad)
                d.badind = find(app.bad(d.chToPlot));
            else
                d.badind = [];
            end
            d.nchan = size(d.data,2);
        end
    end
    
    methods (Access = public)
        
        function trimData(app,beginTime,endTime)
            % convert time to samples and delete
            beginSample = int16(beginTime * app.hdr.sr + 1);
            endSample = int16(endTime * app.hdr.sr + 1);
            app.Raw_Data(:,beginSample:endSample,:) = [];
            app.time = [0:1/app.hdr.sr:(size(app.Raw_Data,2)-1)/app.hdr.sr]'; % probably need marker for discontinuity, but for now okay
            if ~isempty(app.hb_Data)
                app.hb_Data = []; % remove to recompute
            end
            app.WavelengthsButton.Value = 1;
            app.Working_Data = app.Raw_Data;
            app.Current_Data = squeeze(app.Working_Data(1,:,:));
            app.nmButton_760.Value = 1;
            plotData(app,dataForPlot(app));
        end

        
        function computeMBLL(app,ec,dpf,cdist,motion)
            % raw intensity to optical density
            od = nirx_OD(app.Raw_Data);
            switch motion
                case 'TDDR'
                    od(1,:,:) = TDDR(squeeze(od(1,:,:)),app.hdr.sr);
                    od(2,:,:) = TDDR(squeeze(od(2,:,:)),app.hdr.sr);
                case 'splineSG'
                    od(1,:,:) = nirx_motion_spline(squeeze(od(1,:,:)),app.time,5,.99);
                    od(2,:,:) = nirx_motion_spline(squeeze(od(2,:,:)),app.time,5,.99);
            end
            app.Working_Data = od;
            % convert optical measurements to hemoglobin units
            hbo = zeros(app.hdr.nchan,size(app.Raw_Data,2));
            hbr = hbo;
            hbt = hbo;
            for chn = 1:app.hdr.nchan
                tmp = squeeze(od(:,:,chn));
                % MBLL, giving channel distance in cm
                [hbo(chn,:),hbr(chn,:),hbt(chn,:)] = ...
                    nirx_mbll(tmp,dpf,ec,cdist(chn)/10); % Beer-Lambert Law
            end
            if strcmpi(motion,'CBSI')
                [hbo,hbr,hbt] = nirx_motion_cbsi(hbo,hbr);
            end
            app.hb_Data.hbo = hbo'; app.hb_Data.hbr = hbr'; app.hb_Data.hbt = hbt';
            app.Current_Data = app.hb_Data.hbo;
            app.nmButton_850.Value = 1;
            app.WavelengthButtonGroup.Enable = "off";
            app.HbButtonGroup.Enable = "on";
            app.HbButton.Value = 1;
            app.HbOButton.Value = 1;
            plotData(app,dataForPlot(app));
        end
        
        function markBad(app,bad)
            app.bad = bad;
            plotData(app,dataForPlot(app));
        end

        function filterData(app,coefficients)
            app.Working_Data = nirx_filter(app.Working_Data,app.hdr,'coeff',coefficients);
            app.Current_Data = squeeze(app.Working_Data(1,:,:));
            % select current data from working data
            plotData(app,dataForPlot(app));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: WavelengthButtonGroup
        function WavelengthButtonGroupSelectionChanged(app, event)
            selectedButton = app.WavelengthButtonGroup.SelectedObject; % make this more generic
            if strcmpi(selectedButton.Text,'760 nm')
                app.Current_Data = squeeze(app.Working_Data(1,:,:));
            else
                app.Current_Data = squeeze(app.Working_Data(2,:,:));
            end
            plotData(app,dataForPlot(app));
        end

        % Value changed function: RemoveBaselineCheckBox
        function RemoveBaselineCheckBoxValueChanged(app, event)
            plotData(app,dataForPlot(app));
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Wavelengths')
                app.Current_Data = squeeze(app.Raw_Data(1,:,:));
                app.WavelengthButtonGroup.Enable = "on";
                app.nmButton_760.Value = 1;
                app.HbButtonGroup.Enable = "off";
            else
                app.HbButtonGroup.Enable = "on";
                app.WavelengthButtonGroup.Enable = "off";
                if isempty(app.hb_Data) % could just call the menu dialog here, or send defaults to computeMBLL.m
                    % raw intensity to optical density
                    od = nirx_OD(app.Raw_Data);
                    % convert optical measurements to hemoglobin units
                    ec1 = nirx_ecoeff(app.hdr.wl(1));
                    ec2 = nirx_ecoeff(app.hdr.wl(2));
                    ec  = [ec1;ec2];
                    dpf = nirx_DPF([app.hdr.wl(1) app.hdr.wl(2)],20); % change hardcoding of age
                    hbo = zeros(app.hdr.nchan,size(app.Raw_Data,2));
                    hbr = hbo;
                    hbt = hbo;
                    for chn = 1:app.hdr.nchan
                        tmp = squeeze(od(:,:,chn));
                        cdist = app.hdr.dist(chn); % default header distances
                        % MBLL, giving channel distance in cm
                        [hbo(chn,:),hbr(chn,:),hbt(chn,:)] = ...
                            nirx_mbll(tmp,dpf,ec,cdist/10); % Beer-Lambert Law
                    end
                    app.hb_Data.hbo = hbo'; app.hb_Data.hbr = hbr'; app.hb_Data.hbt = hbt';
                end
                app.Current_Data = app.hb_Data.hbo;                
            end
            plotData(app,dataForPlot(app));
        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            plotData(app,dataForPlot(app));
        end

        % Selection changed function: HbButtonGroup
        function HbButtonGroupSelectionChanged(app, event)
            selectedButton = app.HbButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'HbO'
                    app.Current_Data = app.hb_Data.hbo;
                case 'HbR'
                    app.Current_Data = app.hb_Data.hbr;
                case 'HbT'
                    app.Current_Data = app.hb_Data.hbt;               
            end
            plotData(app,dataForPlot(app));
        end

        % Value changed function: OverlayEventsCheckBox
        function OverlayEventsCheckBoxValueChanged(app, event)
            plotData(app,dataForPlot(app));
        end

        % Value changed function: ScaleSpinner
        function ScaleSpinnerValueChanged(app, event)
            plotData(app,dataForPlot(app));
        end

        % Menu selected function: TrimBadDataMenu
        function TrimBadDataMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = fnirs_trimDialogBox(app,app.time);
        end

        % Close request function: fNIRSDataViewerUIFigure
        function fNIRSDataViewerUIFigureCloseRequest(app, event)
            delete(app.DialogApp);
            delete(app);
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            [file, pth, ~] = uigetfile('*.hdr','Select a file to load');
            [~,filebase,~] = fileparts(file);
            cd(pth);
            app.hdr = nirx_read_hdr([filebase '.hdr']);
            [app.Raw_Data, ~, ~, ~] = nirx_read_wl(filebase,app.hdr);
            app.Ylim = [min(app.Raw_Data(:)) max(app.Raw_Data(:))];
            app.time = [0:1/app.hdr.sr:(size(app.Raw_Data,2)-1)/app.hdr.sr]';
            app.Working_Data = app.Raw_Data;
            app.Current_Data = squeeze(app.Working_Data(1,:,:));
            app.hb_Data = [];
            app.spacing = 1;
            app.bad = [];
            app.DataTypeButtonGroup.Enable = "on";
            app.WavelengthButtonGroup.Enable = "on";
            app.RemoveBaselineCheckBox.Enable = "on";
            app.PlotTypeButtonGroup.Enable = "on";
            app.OverlayEventsCheckBox.Enable = "on";
            app.ChnMaxSpinner.Enable = "on";
            app.ChnMinSpinner.Enable = "on";
            app.ChnMinSpinner.Limits = [1 app.hdr.nchan]; % using this will need to ensure max >= min + put in separate function
            app.ChnMinSpinner.Value = 1;
            app.ChnMaxSpinner.Limits = [1 app.hdr.nchan];
            app.ChnMaxSpinner.Value = app.hdr.nchan;
            app.ch_ind = 1:app.hdr.nchan;
            app.nmButton_760.Value = 1;
            app.WavelengthsButton.Value = 1;
            app.HbButtonGroup.Enable = "off";
            plotData(app,dataForPlot(app));            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % Delete both apps
            delete(app.DialogApp);
            delete(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            if ~isempty(app.DialogApp)
                if event.Button == 1
                    app.DialogApp.BeginTimeEditField.Value = app.UIAxes.CurrentPoint(1);
                else
                    app.DialogApp.EndTimeEditField.Value = app.UIAxes.CurrentPoint(2);
                end
            end
        end

        % Value changed function: ChannelsDropDown
        function ChannelsDropDownValueChanged(app, event)
            value = app.ChannelsDropDown.Value;
            switch value
                case 'All'
                    app.ch_ind = 1:app.hdr.nchan;
                    app.ChnMinSpinner.Enable = "on";
                    app.ChnMaxSpinner.Enable = "on";
                    app.ChnMinSpinner.Value = app.ch_ind(1);
                    app.ChnMaxSpinner.Value = app.ch_ind(end);
                case 'Long'
                    app.ch_ind = app.hdr.longSDindices;
                    app.ChnMinSpinner.Enable = "off";
                    app.ChnMaxSpinner.Enable = "off";
                case 'Short'
                    app.ch_ind = app.hdr.shortSDindices;
                    app.ChnMinSpinner.Enable = "off";
                    app.ChnMaxSpinner.Enable = "off";
            end
            plotData(app,dataForPlot(app));
        end

        % Menu selected function: MBLLandMotionCorrectionMenu
        function MBLLandMotionCorrectionMenuSelected(app, event)
            % Open the options dialog and pass inputs
            app.DialogApp = fnirs_mbllDialogBox(app,app.hdr,app.chdist);
        end

        % Menu selected function: BadChannelsMenu
        function BadChannelsMenuSelected(app, event)
            app.DialogApp = fnirs_markBadChans(app,app.hdr,app.Raw_Data,app.hb_Data,app.optpos,app.optlabels);
        end

        % Menu selected function: PlotOptodesChannelsMenu
        function PlotOptodesChannelsMenuSelected(app, event)
            app.DialogApp = fnirs_plotOptodes(app,app.hdr, app.optpos, app.optlabels);
        end

        % Value changed function: ChnMinSpinner
        function ChnMinSpinnerValueChanged(app, event)
            plotData(app,dataForPlot(app));
        end

        % Value changed function: ChnMaxSpinner
        function ChnMaxSpinnerValueChanged(app, event)
            plotData(app,dataForPlot(app)); 
        end

        % Menu selected function: LoadOptodeCoordinatesMenu
        function LoadOptodeCoordinatesMenuSelected(app, event)
            [posfile, pth, ~] = uigetfile('*.csv','Select file with optode positions...');
            [~,app.optlabels,app.optpos] = nirx_read_optpos(fullfile(pth,posfile));
            [~, app.chdist] = nirx_sd_dist(app.hdr,app.optpos,app.optlabels,'mask');
        end

        % Menu selected function: SaveFileMenu
        function SaveFileMenuSelected(app, event)
             [savfile, pth, ~] = uiputfile('','Select file to save data...');
             raw = app.Raw_Data;
             hdr = app.hdr;
             time = app.time;
             save([savfile '.mat'], 'hdr','raw','time');
        end

        % Menu selected function: FilterDataMenu
        function FilterDataMenuSelected(app, event)
            app.DialogApp = fnirs_filterData(app,app.hdr);
        end

        % Menu selected function: FirstLevelStatisticsMenu
        function FirstLevelStatisticsMenuSelected(app, event)
            app.DialogApp = fnirs_levelOneStats(app,app.hdr,size(app.Raw_Data,2));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.fNIRSDataViewerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {238, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fNIRSDataViewerUIFigure = uifigure('Visible', 'off');
            app.fNIRSDataViewerUIFigure.Name = 'fNIRS Data Viewer';
            app.fNIRSDataViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @fNIRSDataViewerUIFigureCloseRequest, true);
            app.fNIRSDataViewerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.fNIRSDataViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'Open File';
            app.LoadOptodeCoordinatesMenu = uimenu(app.FileMenu);
            app.LoadOptodeCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadOptodeCoordinatesMenuSelected, true);
            app.LoadOptodeCoordinatesMenu.Text = 'Load Optode Coordinates';
            app.SaveFileMenu = uimenu(app.FileMenu);
            app.SaveFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFileMenu.Text = 'Save File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.PreprocessMenu = uimenu(app.fNIRSDataViewerUIFigure);
            app.PreprocessMenu.Text = 'Preprocess';
            app.PlotOptodesChannelsMenu = uimenu(app.PreprocessMenu);
            app.PlotOptodesChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotOptodesChannelsMenuSelected, true);
            app.PlotOptodesChannelsMenu.Text = 'Plot Optodes/Channels';
            app.TrimBadDataMenu = uimenu(app.PreprocessMenu);
            app.TrimBadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @TrimBadDataMenuSelected, true);
            app.TrimBadDataMenu.Text = 'Trim Bad Data';
            app.BadChannelsMenu = uimenu(app.PreprocessMenu);
            app.BadChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @BadChannelsMenuSelected, true);
            app.BadChannelsMenu.Text = 'Bad Channels';
            app.MBLLandMotionCorrectionMenu = uimenu(app.PreprocessMenu);
            app.MBLLandMotionCorrectionMenu.MenuSelectedFcn = createCallbackFcn(app, @MBLLandMotionCorrectionMenuSelected, true);
            app.MBLLandMotionCorrectionMenu.Text = 'MBLL and Motion Correction';
            app.FilterDataMenu = uimenu(app.PreprocessMenu);
            app.FilterDataMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterDataMenuSelected, true);
            app.FilterDataMenu.Text = 'Filter Data';
            app.FirstLevelStatisticsMenu = uimenu(app.PreprocessMenu);
            app.FirstLevelStatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @FirstLevelStatisticsMenuSelected, true);
            app.FirstLevelStatisticsMenu.Text = 'First Level Statistics';
            app.GridLayout = uigridlayout(app.fNIRSDataViewerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Tooltip = {'Quick and dirty. Use preprocess menu for complete options.'};
            app.DataTypeButtonGroup.Enable = 'off';
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.WavelengthsButton = uiradiobutton(app.DataTypeButtonGroup);
            app.WavelengthsButton.Text = 'Wavelengths';
            app.WavelengthsButton.Value = true;
            app.HbButton = uiradiobutton(app.DataTypeButtonGroup);
            app.HbButton.Text = 'Hb';
            app.WavelengthButtonGroup = uibuttongroup(app.LeftPanel);
            app.WavelengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WavelengthButtonGroupSelectionChanged, true);
            app.WavelengthButtonGroup.Enable = 'off';
            app.WavelengthButtonGroup.Title = 'Wavelength';
            app.nmButton_850 = uiradiobutton(app.WavelengthButtonGroup);
            app.nmButton_850.Text = '850 nm';
            app.nmButton_760 = uiradiobutton(app.WavelengthButtonGroup);
            app.nmButton_760.Text = '760 nm';
            app.nmButton_760.Value = true;
            app.RemoveBaselineCheckBox = uicheckbox(app.LeftPanel);
            app.RemoveBaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBaselineCheckBoxValueChanged, true);
            app.RemoveBaselineCheckBox.Enable = 'off';
            app.RemoveBaselineCheckBox.Tooltip = {'Remove mean for each channel.'};
            app.RemoveBaselineCheckBox.Text = 'Remove Baseline';
            app.PlotTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Enable = 'off';
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.ButterflyButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ButterflyButton.Text = 'Butterfly';
            app.ButterflyButton.Value = true;
            app.StackedButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.HbButtonGroup = uibuttongroup(app.LeftPanel);
            app.HbButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HbButtonGroupSelectionChanged, true);
            app.HbButtonGroup.Tooltip = {''};
            app.HbButtonGroup.Enable = 'off';
            app.HbButtonGroup.Title = 'Hb';
            app.HbTButton = uiradiobutton(app.HbButtonGroup);
            app.HbTButton.Text = 'HbT';
            app.HbRButton = uiradiobutton(app.HbButtonGroup);
            app.HbRButton.Text = 'HbR';
            app.HbOButton = uiradiobutton(app.HbButtonGroup);
            app.HbOButton.Text = 'HbO';
            app.HbOButton.Value = true;
            app.ChnMinSpinnerLabel = uilabel(app.LeftPanel);
            app.ChnMinSpinnerLabel.Text = 'Chn Min';
            app.ChnMinSpinner = uispinner(app.LeftPanel);
            app.ChnMinSpinner.ValueChangedFcn = createCallbackFcn(app, @ChnMinSpinnerValueChanged, true);
            app.ChnMinSpinner.Enable = 'off';
            app.ChnMaxSpinnerLabel = uilabel(app.LeftPanel);
            app.ChnMaxSpinnerLabel.Text = 'Chn Max';
            app.ChnMaxSpinner = uispinner(app.LeftPanel);
            app.ChnMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @ChnMaxSpinnerValueChanged, true);
            app.ChnMaxSpinner.Enable = 'off';
            app.OverlayEventsCheckBox = uicheckbox(app.LeftPanel);
            app.OverlayEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayEventsCheckBoxValueChanged, true);
            app.OverlayEventsCheckBox.Enable = 'off';
            app.OverlayEventsCheckBox.Text = 'Overlay Events';
            app.ScaleSpinnerLabel = uilabel(app.LeftPanel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.LeftPanel);
            app.ScaleSpinner.Step = 0.25;
            app.ScaleSpinner.Limits = [1 20];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleSpinnerValueChanged, true);
            app.ScaleSpinner.Value = 1;
            app.ChannelsDropDownLabel = uilabel(app.LeftPanel);
            app.ChannelsDropDownLabel.Text = 'Channels';
            app.ChannelsDropDown = uidropdown(app.LeftPanel);
            app.ChannelsDropDown.Items = {'All', 'Long', 'Short'};
            app.ChannelsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelsDropDownValueChanged, true);
            app.ChannelsDropDown.Value = 'All';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.fNIRSDataViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_dataViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fNIRSDataViewerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fNIRSDataViewerUIFigure)
        end
    end
end