classdef fnirs_filterData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        MovingAvgTimesecEditField       matlab.ui.control.NumericEditField
        MovingAvgTimesecEditFieldLabel  matlab.ui.control.Label
        FilterOrderDropDown             matlab.ui.control.DropDown
        FilterOrderDropDownLabel        matlab.ui.control.Label
        FilterSettingsLabel             matlab.ui.control.Label
        HighCutoffDropDown              matlab.ui.control.DropDown
        HighCutoffDropDownLabel         matlab.ui.control.Label
        LowCutoffDropDown               matlab.ui.control.DropDown
        LowCutoffDropDownLabel          matlab.ui.control.Label
        FiltertypeListBox               matlab.ui.control.ListBox
        FiltertypeListBoxLabel          matlab.ui.control.Label
        ApplyFiltertoDataButton         matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        hdr % hdr info from main app
        b % filter coefficients
        a % filter coefficients
        CallingApp % main app handle
    end

    % Custom functions for app
    methods (Access = private)
        
        function plotResponse(app)
            % calculate transfer function
            sr = app.hdr.sr;
            order = str2double(app.FilterOrderDropDown.Value);
            switch app.FiltertypeListBox.Value
                case 'Low pass'
                    cutoffs = str2double(app.HighCutoffDropDown.Value);
                    [app.b, app.a] = butter(order, cutoffs/(sr/2), 'low');
                case 'High pass'
                    cutoffs = str2double(app.LowCutoffDropDown.Value);
                    [app.b, app.a] = butter(order, cutoffs/(sr/2), 'high');
                case 'Band pass'
                    cutoffs(1) = str2double(app.HighCutoffDropDown.Value);
                    cutoffs(2) = str2double(app.LowCutoffDropDown.Value);
                    [app.b, app.a] = butter(order, [cutoffs(1) cutoffs(2)]/(sr/2),'bandpass');
                otherwise
                    warning('Type unsupported at this time!');
            end
            % plot frequency response
            app.a = double(app.a);
            app.b = double(app.b);
            [h,w]=freqz(app.b,app.a,128,sr);
            plot(app.UIAxes,w/pi,20*log10(abs(h)))
            xlabel(app.UIAxes,'Frequency (Hz)'); %'Normalized Frequency (\times\pi rad/sample)');
            ylabel(app.UIAxes,'Magnitude (dB)');
            title(app.UIAxes,'Magnitude Response');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr)
            app.CallingApp = caller;
            app.hdr = hdr;
            plotResponse(app);
        end

        % Value changed function: FilterOrderDropDown
        function FilterOrderDropDownValueChanged(app, event)
            plotResponse(app);           
        end

        % Value changed function: HighCutoffDropDown
        function HighCutoffDropDownValueChanged(app, event)
            plotResponse(app);
        end

        % Value changed function: LowCutoffDropDown
        function LowCutoffDropDownValueChanged(app, event)
            plotResponse(app);     
        end

        % Button pushed function: ApplyFiltertoDataButton
        function ApplyFiltertoDataButtonPushed(app, event)
            % send current filter coefficients to main application
            filterData(app.CallingApp,[app.b;app.a])
            % Delete the filter dialog box
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {193, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ApplyFiltertoDataButton = uibutton(app.LeftPanel, 'push');
            app.ApplyFiltertoDataButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltertoDataButtonPushed, true);
            app.ApplyFiltertoDataButton.Text = 'Apply Filter to Data';
            app.FiltertypeListBoxLabel = uilabel(app.LeftPanel);
            app.FiltertypeListBoxLabel.Text = 'Filter type';
            app.FiltertypeListBox = uilistbox(app.LeftPanel);
            app.FiltertypeListBox.Items = {'Low pass', 'High pass', 'Band pass', 'Moving Average'};
            app.FiltertypeListBox.Value = 'Band pass';
            app.LowCutoffDropDownLabel = uilabel(app.LeftPanel);
            app.LowCutoffDropDownLabel.Text = 'Low Cutoff';
            app.LowCutoffDropDown = uidropdown(app.LeftPanel);
            app.LowCutoffDropDown.Items = {'.005', '.01'};
            app.LowCutoffDropDown.ValueChangedFcn = createCallbackFcn(app, @LowCutoffDropDownValueChanged, true);
            app.LowCutoffDropDown.Value = '.01';
            app.HighCutoffDropDownLabel = uilabel(app.LeftPanel);
            app.HighCutoffDropDownLabel.Text = 'High Cutoff';
            app.HighCutoffDropDown = uidropdown(app.LeftPanel);
            app.HighCutoffDropDown.Items = {'.05', '.08', '.1'};
            app.HighCutoffDropDown.ValueChangedFcn = createCallbackFcn(app, @HighCutoffDropDownValueChanged, true);
            app.HighCutoffDropDown.Value = '.05';
            app.FilterSettingsLabel = uilabel(app.LeftPanel);
            app.FilterSettingsLabel.Text = 'Filter Settings';
            app.FilterOrderDropDownLabel = uilabel(app.LeftPanel);
            app.FilterOrderDropDownLabel.Text = 'Filter Order';
            app.FilterOrderDropDown = uidropdown(app.LeftPanel);
            app.FilterOrderDropDown.Items = {'2', '3', '4', '5', '6'};
            app.FilterOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterOrderDropDownValueChanged, true);
            app.FilterOrderDropDown.Value = '4';
            app.MovingAvgTimesecEditFieldLabel = uilabel(app.LeftPanel);
            app.MovingAvgTimesecEditFieldLabel.Enable = 'off';
            app.MovingAvgTimesecEditFieldLabel.Text = 'Moving Avg Time (sec)';
            app.MovingAvgTimesecEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MovingAvgTimesecEditField.Editable = 'off';
            app.MovingAvgTimesecEditField.Enable = 'off';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Note: All filters implemented (except moving average) are Butterworth characteristic and applied forward/reverse to the data.';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_filterData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end