classdef fnirs_levelOneStats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticsDialog                matlab.ui.Figure
        LoadAdditionalRegressorButton   matlab.ui.control.Button
        ShortChannelOptionsButtonGroup  matlab.ui.container.ButtonGroup
        NearestButton                   matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        DonotincludeButton              matlab.ui.control.RadioButton
        EstimateButton                  matlab.ui.control.Button
        BaselineOptionsButtonGroup      matlab.ui.container.ButtonGroup
        IncludeBaselineButton           matlab.ui.control.RadioButton
        ImplicitBaselineButton          matlab.ui.control.RadioButton
        PlotDesignButton                matlab.ui.control.Button
        InfoTable                       matlab.ui.control.Table
        BaselineConditionDropDown       matlab.ui.control.DropDown
        BaselineConditionDropDownLabel  matlab.ui.control.Label
        LoadEVTFileButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       CallingApp % App calling dialog
       hdr % header
       onsets % condition onsets (if from evt in samples, may need to convert to seconds
       vals % condition values
       npoints % number of samples per channel
       M % Design matrix details, M.X is matrix
    end
    
    methods (Access = private)

        function create_design(app)
            X = [];
            X.basis = 'hrf';
            codes = app.InfoTable.Data.codes;
            durations = app.InfoTable.Data.durs;
            nreps = length([app.hdr.events.code]);
            dur = zeros(1,nreps);
            for code = 1:length(codes)
                cind = find([app.hdr.events.code] == codes(code));
                dur(cind) = durations(code);
            end
            X.dur = dur;
            X.sr = app.hdr.sr;
            X.nsamp = app.npoints;
            X.names = app.InfoTable.Data.names;
            X.values = [app.hdr.events.code];
            X.onsets = [app.hdr.events.samp];
            if app.ImplicitBaselineButton.Value
                X.implicit = 'yes';
            else
                X.implicit = 'no';
            end
            X.baseline = str2double(app.BaselineConditionDropDown.Value);
            M = nirx_design_matrix(X,0);
            app.M = M;
        end
        
        function plotDesign(app)
            create_design(app);
            imagesc(app.UIAxes,app.M.X);
            axis(app.UIAxes,'tight');
            colormap(app.UIAxes,gray);
            labels = [{'Constant'};app.M.names];
            xticks(app.UIAxes,1:length(labels));
            xticklabels(app.UIAxes,labels);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr, npoints)
            app.CallingApp = caller;
            app.hdr = hdr;
            app.npoints = npoints;
            codes = unique([app.hdr.events.code])';
            app.BaselineConditionDropDown.Items = strsplit(num2str(codes'));
            names = repmat({'Name?'},1,length(codes))';
            durs = repmat(30,1,length(codes))';
            t = table(codes,names,durs);
            app.InfoTable.Data = t; % access by ...Data.codes ...Data.names ...Data.durs
            app.InfoTable.RowName = 'numbered';
        end

        % Button pushed function: LoadEVTFileButton
        function LoadEVTFileButtonPushed(app, event)
            evt_file = uigetfile("*.evt");
            [app.onsets,app.vals] =nirx_read_evt(evt_file);
        end

        % Button pushed function: PlotDesignButton
        function PlotDesignButtonPushed(app, event)
            plotDesign(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticsDialog = uifigure('Visible', 'off');
            app.StatisticsDialog.Name = '1st Level Statistics';
            app.UIAxes = uiaxes(app.StatisticsDialog);
            title(app.UIAxes, 'Design Matrix')
            xlabel(app.UIAxes, 'Regressors')
            ylabel(app.UIAxes, 'Time (samples)')
            zlabel(app.UIAxes, 'Z')
            app.LoadEVTFileButton = uibutton(app.StatisticsDialog, 'push');
            app.LoadEVTFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEVTFileButtonPushed, true);
            app.LoadEVTFileButton.Text = 'Load .EVT File';
            app.BaselineConditionDropDownLabel = uilabel(app.StatisticsDialog);
            app.BaselineConditionDropDownLabel.Text = 'Baseline Condition';
            app.BaselineConditionDropDown = uidropdown(app.StatisticsDialog);
            app.BaselineConditionDropDown.Items = {'No Baseline'};
            app.BaselineConditionDropDown.Value = 'No Baseline';
            app.InfoTable = uitable(app.StatisticsDialog);
            app.InfoTable.ColumnName = {'Code'; 'Name'; 'Duration (sec)'};
            app.InfoTable.RowName = {};
            app.InfoTable.ColumnEditable = true;
            app.PlotDesignButton = uibutton(app.StatisticsDialog, 'push');
            app.PlotDesignButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDesignButtonPushed, true);
            app.PlotDesignButton.Text = 'Plot Design';
            app.BaselineOptionsButtonGroup = uibuttongroup(app.StatisticsDialog);
            app.BaselineOptionsButtonGroup.Title = 'Baseline Options';
            app.ImplicitBaselineButton = uiradiobutton(app.BaselineOptionsButtonGroup);
            app.ImplicitBaselineButton.Text = 'Implicit Baseline';
            app.ImplicitBaselineButton.Value = true;
            app.IncludeBaselineButton = uiradiobutton(app.BaselineOptionsButtonGroup);
            app.IncludeBaselineButton.Text = 'Include Baseline';
            app.EstimateButton = uibutton(app.StatisticsDialog, 'push');
            app.EstimateButton.Text = 'Estimate';
            app.ShortChannelOptionsButtonGroup = uibuttongroup(app.StatisticsDialog);
            app.ShortChannelOptionsButtonGroup.Title = 'Short Channel Options';
            app.DonotincludeButton = uiradiobutton(app.ShortChannelOptionsButtonGroup);
            app.DonotincludeButton.Text = 'Do not include';
            app.DonotincludeButton.Value = true;
            app.PCAButton = uiradiobutton(app.ShortChannelOptionsButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.NearestButton = uiradiobutton(app.ShortChannelOptionsButtonGroup);
            app.NearestButton.Text = 'Nearest';
            app.LoadAdditionalRegressorButton = uibutton(app.StatisticsDialog, 'push');
            app.LoadAdditionalRegressorButton.Text = 'Load Additional Regressor';
            % Show the figure after all components are created
            app.StatisticsDialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_levelOneStats(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.StatisticsDialog)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.StatisticsDialog)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticsDialog)
        end
    end
end