classdef fnirs_mbllDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModifiedBeerLambertLawUIFigure  matlab.ui.Figure
        DefaultDistancesLoadedLabel     matlab.ui.control.Label
        MotionCorrectionButtonGroup     matlab.ui.container.ButtonGroup
        SplineSG                        matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        CBSIButton                      matlab.ui.control.RadioButton
        TDDRButton                      matlab.ui.control.RadioButton
        ChannelDistancesButtonGroup     matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        DefaultsButton                  matlab.ui.control.RadioButton
        nmEditField_850                 matlab.ui.control.NumericEditField
        nmLabel                         matlab.ui.control.Label
        nmEditField_760                 matlab.ui.control.NumericEditField
        nmLabel_2                       matlab.ui.control.Label
        DPFLabel                        matlab.ui.control.Label
        ApplyMBLLButton                 matlab.ui.control.Button
        LoadOptodeCoordinatesButton     matlab.ui.control.Button
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        ExtinctionCoefficientsDropDown  matlab.ui.control.DropDown
        ExtinctionCoefficientsDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % App calling dialog
        hdr % header from calling app
        ec % extinction coefficients at wl for fnirs
        iec % interpolated extinction coefficients
        allwl % all wavelengths in extinction table
        dpf % pathlength factors
        chdist % channel distances
        motion % motion correction method
    end

    methods (Access = private)
        
        function plotIEC(app)
            h = plot(app.UIAxes,app.allwl,app.iec);
            x_lim = app.UIAxes.XLim;
            app.UIAxes.XLim = [700 app.allwl(end)]; % limit to near infrared
            %axis(app.UIAxes,'tight');
            hold(app.UIAxes,"on");
            scatter(app.UIAxes,[app.hdr.wl(1) app.hdr.wl(1)],app.ec(1,:));
            scatter(app.UIAxes,[app.hdr.wl(2) app.hdr.wl(2)],app.ec(2,:));
            hold(app.UIAxes,"off");
            legend(h,{'HbO' 'HbR'});
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr, chdist)
            app.CallingApp  = caller;
            app.hdr = hdr;
            if ~isempty(chdist)
                app.chdist = chdist;
                app.DefaultDistancesLoadedLabel.Text = 'Custom Distances Loaded';
                app.CustomButton.Value = 1;
            else
                app.chdist = app.hdr.dist;
                app.DefaultsButton.Value = 1;
            end
            [app.ec,app.iec,app.allwl] = nirx_ecoeff(app.hdr.wl,'Cope'); % default table
            plotIEC(app);
            app.dpf = nirx_DPF(app.hdr.wl,app.AgeEditField.Value);
            app.nmEditField_760.Value = app.dpf(1);
            app.nmEditField_850.Value = app.dpf(2);
            app.TDDRButton.Value = 1;
            app.motion = 'TDDR';
        end

        % Value changed function: ExtinctionCoefficientsDropDown
        function ExtinctionCoefficientsDropDownValueChanged(app, event)
            value = app.ExtinctionCoefficientsDropDown.Value;
            [app.ec,app.iec,app.allwl] = nirx_ecoeff(app.hdr.wl,value);
            plotIEC(app);
        end

        % Button pushed function: ApplyMBLLButton
        function ApplyMBLLButtonPushed(app, event)
            % Call main app's public function
            computeMBLL(app.CallingApp,app.ec,app.dpf,app.chdist,app.motion);
            % Delete the dialog box
            delete(app);
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            value = app.AgeEditField.Value;
            app.dpf = nirx_DPF(app.hdr.wl,value);
            app.nmEditField_760.Value = app.dpf(1);
            app.nmEditField_850.Value = app.dpf(2);
        end

        % Button pushed function: LoadOptodeCoordinatesButton
        function LoadOptodeCoordinatesButtonPushed(app, event)
            [posfile, pth, ~] = uigetfile('*.csv','Select file with optode positions...');
            [~,lbl,pos]=nirx_read_optpos(fullfile(pth,posfile));
            [app.chdist,~]=nirx_chan_dist(app.hdr,pos,lbl,[10 50],'mask','all');
            app.CustomButton.Value = 1;
        end

        % Selection changed function: MotionCorrectionButtonGroup
        function MotionCorrectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MotionCorrectionButtonGroup.SelectedObject;
            app.motion = selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModifiedBeerLambertLawUIFigure = uifigure('Visible', 'off');
            app.ModifiedBeerLambertLawUIFigure.Name = 'Modified Beer Lambert Law';
            app.UIAxes = uiaxes(app.ModifiedBeerLambertLawUIFigure);
            title(app.UIAxes, 'Molar Extinction Coefficients')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Absorption (cm-1/M)')
            zlabel(app.UIAxes, 'Z')
            app.ExtinctionCoefficientsDropDownLabel = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.ExtinctionCoefficientsDropDownLabel.Text = 'Extinction Coefficients';
            app.ExtinctionCoefficientsDropDown = uidropdown(app.ModifiedBeerLambertLawUIFigure);
            app.ExtinctionCoefficientsDropDown.Items = {'Cope', 'Prahl', 'Moaveni', 'Takatani'};
            app.ExtinctionCoefficientsDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtinctionCoefficientsDropDownValueChanged, true);
            app.ExtinctionCoefficientsDropDown.Value = 'Cope';
            app.AgeEditFieldLabel = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.ModifiedBeerLambertLawUIFigure, 'numeric');
            app.AgeEditField.Limits = [1 100];
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.AgeEditField.Tooltip = {'Age of subject'};
            app.AgeEditField.Value = 20;
            app.LoadOptodeCoordinatesButton = uibutton(app.ModifiedBeerLambertLawUIFigure, 'push');
            app.LoadOptodeCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptodeCoordinatesButtonPushed, true);
            app.LoadOptodeCoordinatesButton.Tooltip = {'Load if you want to override channel distance defaults (30 mm for long, 8 mm for short)'};
            app.LoadOptodeCoordinatesButton.Text = 'Load Optode Coordinates';
            app.ApplyMBLLButton = uibutton(app.ModifiedBeerLambertLawUIFigure, 'push');
            app.ApplyMBLLButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMBLLButtonPushed, true);
            app.ApplyMBLLButton.Text = 'Apply MBLL';
            app.DPFLabel = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.DPFLabel.Tooltip = {'Differential Pathlength Factor'};
            app.DPFLabel.Text = 'DPF';
            app.nmLabel_2 = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.nmLabel_2.Text = '760 nm';
            app.nmEditField_760 = uieditfield(app.ModifiedBeerLambertLawUIFigure, 'numeric');
            app.nmEditField_760.Editable = 'off';
            app.nmLabel = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.nmLabel.Text = '850 nm';
            app.nmEditField_850 = uieditfield(app.ModifiedBeerLambertLawUIFigure, 'numeric');
            app.nmEditField_850.Editable = 'off';
            app.ChannelDistancesButtonGroup = uibuttongroup(app.ModifiedBeerLambertLawUIFigure);
            app.ChannelDistancesButtonGroup.Tooltip = {'Indicates whether default channel distances or custom are used'};
            app.ChannelDistancesButtonGroup.Enable = 'off';
            app.ChannelDistancesButtonGroup.Title = 'Channel Distances';
            app.DefaultsButton = uiradiobutton(app.ChannelDistancesButtonGroup);
            app.DefaultsButton.Text = 'Defaults';
            app.DefaultsButton.Value = true;
            app.CustomButton = uiradiobutton(app.ChannelDistancesButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.MotionCorrectionButtonGroup = uibuttongroup(app.ModifiedBeerLambertLawUIFigure);
            app.MotionCorrectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MotionCorrectionButtonGroupSelectionChanged, true);
            app.MotionCorrectionButtonGroup.Title = 'Motion Correction';
            app.TDDRButton = uiradiobutton(app.MotionCorrectionButtonGroup);
            app.TDDRButton.Text = 'TDDR';
            app.TDDRButton.Value = true;
            app.CBSIButton = uiradiobutton(app.MotionCorrectionButtonGroup);
            app.CBSIButton.Text = 'CBSI';
            app.NoneButton = uiradiobutton(app.MotionCorrectionButtonGroup);
            app.NoneButton.Text = 'None';
            app.SplineSG = uiradiobutton(app.MotionCorrectionButtonGroup);
            app.SplineSG.Text = 'SplineSG';
            app.DefaultDistancesLoadedLabel = uilabel(app.ModifiedBeerLambertLawUIFigure);
            app.DefaultDistancesLoadedLabel.Text = 'Default Distances Loaded';
            % Show the figure after all components are created
            app.ModifiedBeerLambertLawUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_mbllDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModifiedBeerLambertLawUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModifiedBeerLambertLawUIFigure)
        end
    end
end