classdef fnirs_plotOptodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptodesandChannelsDialogUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PlotLabelsCheckBox         matlab.ui.control.CheckBox
        ViewButtonGroup            matlab.ui.container.ButtonGroup
        DButton_3D                 matlab.ui.control.RadioButton
        DButton_2D                 matlab.ui.control.RadioButton
        LoadOptodePositionsButton  matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        hdr % header from main
        opt3d % 3d optode positions
        opt2d % 2d optode positions
        chn2d % 2d channel positions
        chn3d % 3d channel positions
        top % cartoon top of head
        rows % size of top
        columns % size of top
        chnlabels % channel labels
        optlabels % optode labels
        surfaces % 3d surfaces structure to plot in 3d
        braincolor
        scalpcolor
        CallingApp % App calling dialog
    end
    
    methods (Access = private)
        
        function plotLocations(app)
            selected = app.ViewButtonGroup.SelectedObject;
            sind=find(contains(app.optlabels,'S'));
            dind=find(contains(app.optlabels,'D'));
            switch selected.Text
                case "2D"
                    cla(app.UIAxes,'reset');
                    rotate3d(app.UIAxes,'off');
                    src2d = app.opt2d(sind,:);
                    det2d = app.opt2d(dind,:);
                    imagesc(app.UIAxes,app.top);
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes,src2d(:,1),src2d(:,2),'MarkerEdgeColor','r','MarkerFaceColor','r');
                    scatter(app.UIAxes,det2d(:,1),det2d(:,2),'MarkerEdgeColor','b','MarkerFaceColor','b');
                    for chn = 1:app.hdr.nchan
                        src = app.hdr.SDpairs(chn,1);
                        det = app.hdr.SDpairs(chn,2);
                        line(app.UIAxes,[src2d(src,1) det2d(det,1)],[src2d(src,2) det2d(det,2)]);
                    end
                    if app.PlotLabelsCheckBox.Value
                        text(app.UIAxes,app.opt2d(:,1),app.opt2d(:,2),app.optlabels);
                    end
                    legend(app.UIAxes,{'Sources','Detectors'});
                    hold(app.UIAxes,'off');
                    axis(app.UIAxes,'off');
                case "3D"
                    cla(app.UIAxes,'reset');
                    src3d = app.opt3d(sind,:);
                    det3d = app.opt3d(dind,:);
                    if isprop(app,"surfaces")
                        s = patch(app.UIAxes,'vertices',app.surfaces.scalp.vertices,'faces',app.surfaces.scalp.faces,'edgecolor','none',...
                            'facecolor',app.scalpcolor);
                        S = s.Vertices;
                        alpha(s,.3);
                        axis(app.UIAxes,'image');
                        axis(app.UIAxes,'off');
                        hold(app.UIAxes,'on');
                        c = patch(app.UIAxes,'vertices',app.surfaces.brain.vertices,'faces',app.surfaces.brain.faces,'edgecolor','none',...
                                'facecolor',app.braincolor);
                        if app.PlotLabelsCheckBox.Value
                            [h(1),src_offsets] = nirx_plot_optode3d(src3d,S,app.surfaces.normals,'offset',10,'edgecolor',[1 0 0],...
                                'facealpha',.4,'facecolor',[1 0 0],'labels',app.optlabels,'axis',app.UIAxes);
                            [h(2),det_offsets] = nirx_plot_optode3d(det3d,S,app.surfaces.normals,'offset',10,'edgecolor',[0 0 1],...
                                'facealpha',.4,'facecolor',[0 0 1],'labels',app.optlabels,'axis',app.UIAxes);
                          nirx_plotSD_3d(app.opt3d,sind,'surface',S,'axis',app.UIAxes,'channels',app.hdr.SDpairs,'labels',app.optlabels);
                        else
                            [h(1),src_offsets] = nirx_plot_optode3d(src3d,S,app.surfaces.normals,'offset',10,'edgecolor',[1 0 0],...
                                'facealpha',.4,'facecolor',[1 0 0],'axis',app.UIAxes);
                            [h(2),det_offsets] = nirx_plot_optode3d(det3d,S,app.surfaces.normals,'offset',10,'edgecolor',[0 0 1],...
                                'facealpha',.4,'facecolor',[0 0 1],'axis',app.UIAxes);
                        end
                        % plot channel lines
                        for chn = 1:app.hdr.nchan
                           src = app.hdr.SDpairs(chn,1);
                           det = app.hdr.SDpairs(chn,2);
                           h(3) = line(app.UIAxes,[src_offsets(src,1) det_offsets(det,1)],[src_offsets(src,2) det_offsets(det,2)],...
                                  [src_offsets(src,3) det_offsets(det,3)], 'LineWidth',2,'Color','g');
                        end
                        legend(app.UIAxes,[h(1) h(2) h(3)],{'Sources' 'Detectors' 'Channels'});
                        camlight(app.UIAxes,'left');
                        camlight(app.UIAxes,'right');
                        lighting(app.UIAxes,'gouraud');
                        rotate3d(app.UIAxes,'on');
                        hold(app.UIAxes,'off');
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, hdr, optpos, optlabels)
            app.CallingApp  = caller;
            app.hdr = hdr;
            [pth,~,~]=fileparts(which('nirx_defaults'));
            app.top = imread(fullfile(pth,'templates','Cartoon_Head_Top.jpg'));
            [app.rows, app.columns, ~] = size(app.top);
            imagesc(app.UIAxes,app.top);
            axis(app.UIAxes,'image');
            axis(app.UIAxes,'off');
            app.DButton_2D.Value = 1; % start in 2D, optode view
            app.scalpcolor = [.8 .8 .8];
            app.braincolor = [200 120 105]./255;
            if ~isempty(optpos)
                app.opt3d = optpos;
                [app.chn3d, app.chnlabels] = nirx_compute_chanlocs(app.optlabels,app.opt3d,app.hdr.SDpairs,app.hdr.shortdetindex);
                app.opt2d = app.columns - rescale(nirx_coords2D(app.opt3d), 1, app.columns);
                app.chn2d = app.columns - rescale(nirx_coords2D(app.chn3d), 1, app.columns);
                app.ViewButtonGroup.Enable = "on";
                app.DButton_2D.Value = 1; % plot 2D first
                plotLocations(app);
            else
                 app.ViewButtonGroup.Enable = "off"; % need to load locations first
                 app.PlotLabelsCheckBox.Enable = "off";
            end
            if ~isempty(app.optlabels)
                app.optlabels = optlabels;
                app.PlotLabelsCheckBox.Enable = "on";
            end
            try
                s = load(fullfile(pth,'templates','mni_surfaces.mat'));
                app.surfaces = s;
            catch
                uialert(app.OptodesandChannelsDialogUIFigure,"Surface file not found.","Invalid File");
                app.DButton_3D.Enable = "off";
            end
        end

        % Button pushed function: LoadOptodePositionsButton
        function LoadOptodePositionsButtonPushed(app, event)
            [posfile, pth, ~] = uigetfile('*.csv','Select file with optode positions...');
            [~,app.optlabels,app.opt3d]=nirx_read_optpos(fullfile(pth,posfile));
            [app.chn3d, app.chnlabels] = nirx_compute_chanlocs(app.optlabels,app.opt3d,app.hdr.SDpairs,app.hdr.shortdetindex);
            % [app.chdist,~] = nirx_chan_dist(app.hdr,app.pos3d,app.optlabels,[10 50],'mask','all');
            app.opt2d = app.columns - rescale(nirx_coords2D(app.opt3d), 1, app.columns);
            app.chn2d = app.columns - rescale(nirx_coords2D(app.chn3d), 1, app.columns);
            app.ViewButtonGroup.Enable = "on";
            app.DButton_2D.Value = 1; % plot 2D first
            app.PlotLabelsCheckBox.Enable = "on";
            plotLocations(app);
        end

        % Value changed function: PlotLabelsCheckBox
        function PlotLabelsCheckBoxValueChanged(app, event)
            plotLocations(app);
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            plotLocations(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OptodesandChannelsDialogUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptodesandChannelsDialogUIFigure = uifigure('Visible', 'off');
            app.OptodesandChannelsDialogUIFigure.Name = 'Optodes and Channels Dialog';
            app.OptodesandChannelsDialogUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OptodesandChannelsDialogUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadOptodePositionsButton = uibutton(app.LeftPanel, 'push');
            app.LoadOptodePositionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptodePositionsButtonPushed, true);
            app.LoadOptodePositionsButton.Text = 'Load Optode Positions';
            app.ViewButtonGroup = uibuttongroup(app.LeftPanel);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.DButton_2D = uiradiobutton(app.ViewButtonGroup);
            app.DButton_2D.Text = '2D';
            app.DButton_2D.Value = true;
            app.DButton_3D = uiradiobutton(app.ViewButtonGroup);
            app.DButton_3D.Text = '3D';
            app.PlotLabelsCheckBox = uicheckbox(app.LeftPanel);
            app.PlotLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotLabelsCheckBoxValueChanged, true);
            app.PlotLabelsCheckBox.Text = 'Plot Labels';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.OptodesandChannelsDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_plotOptodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptodesandChannelsDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptodesandChannelsDialogUIFigure)
        end
    end
end