classdef fnirs_trimDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimDataDialogUIFigure   matlab.ui.Figure
        SetEndtoMaxTimeButton    matlab.ui.control.Button
        SetBegintoMinTimeButton  matlab.ui.control.Button
        SelectEndTimeinMainWindowtoDeleteRightClickLabel  matlab.ui.control.Label
        SelectBeginningTimeinMainWindowtoDeleteLeftClickLabel  matlab.ui.control.Label
        EndTimeEditField         matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel    matlab.ui.control.Label
        BeginTimeEditField       matlab.ui.control.NumericEditField
        BeginTimeEditFieldLabel  matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % App calling dialog
        Time % time from calling app
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, T)
            app.CallingApp  = caller;
            app.BeginTimeEditField.Value = T(end);
            app.EndTimeEditField.Value = T(end);
            app.Time = T;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Call main app's public function
            trimData(app.CallingApp, app.BeginTimeEditField.Value, app.EndTimeEditField.Value);
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            value = app.EndTimeEditField.Value;
            
        end

        % Button pushed function: SetEndtoMaxTimeButton
        function SetEndtoMaxTimeButtonPushed(app, event)
            app.EndTimeEditField.Value = app.Time(end);
        end

        % Button pushed function: SetBegintoMinTimeButton
        function SetBegintoMinTimeButtonPushed(app, event)
            app.BeginTimeEditField.Value = app.Time(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimDataDialogUIFigure = uifigure('Visible', 'off');
            app.TrimDataDialogUIFigure.Name = 'Trim Data Dialog';
            app.DoneButton = uibutton(app.TrimDataDialogUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BeginTimeEditFieldLabel = uilabel(app.TrimDataDialogUIFigure);
            app.BeginTimeEditFieldLabel.Text = 'Begin Time';
            app.BeginTimeEditField = uieditfield(app.TrimDataDialogUIFigure, 'numeric');
            app.EndTimeEditFieldLabel = uilabel(app.TrimDataDialogUIFigure);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.TrimDataDialogUIFigure, 'numeric');
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.SelectBeginningTimeinMainWindowtoDeleteLeftClickLabel = uilabel(app.TrimDataDialogUIFigure);
            app.SelectBeginningTimeinMainWindowtoDeleteLeftClickLabel.Text = 'Select Beginning Time in Main Window to Delete (Left Click)';
            app.SelectEndTimeinMainWindowtoDeleteRightClickLabel = uilabel(app.TrimDataDialogUIFigure);
            app.SelectEndTimeinMainWindowtoDeleteRightClickLabel.Text = 'Select End Time in Main Window to Delete (Right Click)';
            app.SetBegintoMinTimeButton = uibutton(app.TrimDataDialogUIFigure, 'push');
            app.SetBegintoMinTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SetBegintoMinTimeButtonPushed, true);
            app.SetBegintoMinTimeButton.Text = 'Set Begin to Min Time';
            app.SetEndtoMaxTimeButton = uibutton(app.TrimDataDialogUIFigure, 'push');
            app.SetEndtoMaxTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SetEndtoMaxTimeButtonPushed, true);
            app.SetEndtoMaxTimeButton.Text = 'Set End to Max Time';
            % Show the figure after all components are created
            app.TrimDataDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fnirs_trimDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimDataDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimDataDialogUIFigure)
        end
    end
end